classdef FlightEvents < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LandingLamp               matlab.ui.control.Lamp
        LandingLampLabel          matlab.ui.control.Label
        ChuteDeploymentLamp       matlab.ui.control.Lamp
        ChuteDeploymentLampLabel  matlab.ui.control.Label
        EjectionchargeLamp        matlab.ui.control.Lamp
        EjectionchargeLampLabel   matlab.ui.control.Label
        ApogeeLamp                matlab.ui.control.Lamp
        ApogeeLampLabel           matlab.ui.control.Label
        MotorburnoutLamp          matlab.ui.control.Lamp
        MotorburnoutLampLabel     matlab.ui.control.Label
        MaxQLamp                  matlab.ui.control.Lamp
        MaxQLampLabel             matlab.ui.control.Label
        RodclearLamp              matlab.ui.control.Lamp
        RodclearLampLabel         matlab.ui.control.Label
        LoadButton                matlab.ui.control.Button
        LaunchButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        words % Description
        delays % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LaunchButton
        function Launch(app, event)
%             for i = 1:length(app.delays)
%                 pause(app.delays(i))
%                 system(app.words(i));
%                 app.RodclearLamp.Enable='on';
%             end
              pause(app.delays(1))
              app.RodclearLamp.Enable='on';
              system(app.words(1))
              
              pause(app.delays(2))
              app.MaxQLamp.Enable='on';
              system(app.words(2))
              
              pause(app.delays(3))
              app.MotorburnoutLamp.Enable='on';
              system(app.words(3))
              
              pause(app.delays(4))
              app.ApogeeLamp.Enable='on';
              system(app.words(4))
              
              pause(app.delays(5))
              app.EjectionchargeLamp.Enable='on';
              system(app.words(5))
              
              pause(app.delays(6))
              app.ChuteDeploymentLamp.Enable='on';
              system(app.words(6))
              
              pause(app.delays(7))
              app.LandingLamp.Enable='on';
              system(app.words(7))
            
        end

        % Button pushed function: LoadButton
        function Load(app, event)
            load Flight_Events.mat events
            times = cell2mat(events(2, :));
            app.words = string(events(1, :));
            app.delays(1) = times(1);
            for j=2:length(times)
                app.delays(j) = times(j)-times(j-1);
                if app.delays(j) < 0
                    app.delays(j) = 0;
                    warning('Time delay < 0!')
                end
            end
            for k = 1:length(app.delays)
                app.words(k) = append('say ', string(events(1, k)));
            end
            
            app.RodclearLamp.Enable='off';
            app.MaxQLamp.Enable='off';
            app.MotorburnoutLamp.Enable='off';
            app.ApogeeLamp.Enable='off';
            app.EjectionchargeLamp.Enable='off';
            app.ChuteDeploymentLamp.Enable='off';
            app.LandingLamp.Enable='off';
            system('say Ready');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LaunchButton = uibutton(app.UIFigure, 'push');
            app.LaunchButton.ButtonPushedFcn = createCallbackFcn(app, @Launch, true);
            app.LaunchButton.Text = 'Launch';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @Load, true);
            app.LoadButton.Text = 'Load';
            app.RodclearLampLabel = uilabel(app.UIFigure);
            app.RodclearLampLabel.Text = 'Rod clear';
            app.RodclearLamp = uilamp(app.UIFigure);
            app.MaxQLampLabel = uilabel(app.UIFigure);
            app.MaxQLampLabel.Text = 'Max Q';
            app.MaxQLamp = uilamp(app.UIFigure);
            app.MotorburnoutLampLabel = uilabel(app.UIFigure);
            app.MotorburnoutLampLabel.Text = 'Motor burnout';
            app.MotorburnoutLamp = uilamp(app.UIFigure);
            app.ApogeeLampLabel = uilabel(app.UIFigure);
            app.ApogeeLampLabel.Text = 'Apogee';
            app.ApogeeLamp = uilamp(app.UIFigure);
            app.EjectionchargeLampLabel = uilabel(app.UIFigure);
            app.EjectionchargeLampLabel.Text = 'Ejection charge';
            app.EjectionchargeLamp = uilamp(app.UIFigure);
            app.ChuteDeploymentLampLabel = uilabel(app.UIFigure);
            app.ChuteDeploymentLampLabel.Text = 'Chute Deployment';
            app.ChuteDeploymentLamp = uilamp(app.UIFigure);
            app.LandingLampLabel = uilabel(app.UIFigure);
            app.LandingLampLabel.Text = 'Landing';
            app.LandingLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FlightEvents

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end